classdef sense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoMuUIFigure                matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout_Left             matlab.ui.container.GridLayout
        Button_Generate             matlab.ui.control.Button
        GridLayout_Data             matlab.ui.container.GridLayout
        Button_Run                  matlab.ui.control.Button
        Button_Load                 matlab.ui.control.Button
        GridLayout_Generate         matlab.ui.container.GridLayout
        EditField_SNR               matlab.ui.control.EditField
        SNRdBLabel                  matlab.ui.control.Label
        GridLayout_Information      matlab.ui.container.GridLayout
        EditField_Motion_Amplitude  matlab.ui.control.EditField
        Label_6                     matlab.ui.control.Label
        EditField_Motion_Frequency  matlab.ui.control.EditField
        Label_5                     matlab.ui.control.Label
        Label_Information           matlab.ui.control.Label
        Button_Import               matlab.ui.control.Button
        GridLayout_Parameter        matlab.ui.container.GridLayout
        Button_Delete_Tag           matlab.ui.control.Button
        Button_Add_Tag              matlab.ui.control.Button
        EditField_Chirp_F0          matlab.ui.control.EditField
        ChirpHzLabel_2              matlab.ui.control.Label
        EditField_Chirp_BW          matlab.ui.control.EditField
        ChirpHzLabel                matlab.ui.control.Label
        EditField_Chirp_T           matlab.ui.control.EditField
        ChirpsLabel                 matlab.ui.control.Label
        ListBox_Tag                 matlab.ui.control.ListBox
        Label_3                     matlab.ui.control.Label
        EditField_Center_Freq       matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        EditField_FS                matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        GridLayout_Right            matlab.ui.container.GridLayout
        Label_Estimation            matlab.ui.control.Label
        Label_Distance              matlab.ui.control.Label
        UIAxes_Distance             matlab.ui.control.UIAxes
        UIAxes_Estimation           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % 信号相关配置
        fs                  % 载波频率
        center_freq         % 载波中心频率
        T                   % Chirp符号持续时间
        BW                  % Chirp符号带宽
        f0                  % Chirp符号起始频率
        tags                 % 节点频移参考频率
        threshold           % 节点频移频率的误差范围

        % 搜索相关配置
        motion_freq_begin   % 联合估计搜索的运动频率起始
        motion_freq_end     % 联合估计搜索的运动频率结束
        motion_freq_n       % 联合估计搜索的精度

        % 其他配置
        dc_offset           % DC直流偏置消除
        fft_n               % 傅里叶变换长度
        every_window_flag   % 是否逐窗口寻找节点频率（持续跟踪消除傅里叶变换跳变算法）
        snr                 % 生成文件的SNR
        can_run             % 是否可以运行

        % 运动信息
        motion_freq         % 运动频率
        motion_amp          % 运动幅度

        % 信号数据
        data                % 信号
        analyze_result      % 分析的结果
    end

    methods (Access = private)

        function value = getKey(app, config, key, default, required)
            if isfield(config, key)
                value = config.(key);
            else
                value = default;
                if required
                    uialert(app.LoMuUIFigure, ['配置文件中没有给出"' key '"，采用默认值！'], '警告', 'Icon', 'warning');
                end
            end
        end

        function syncFromUI(app)
            app.fs = str2double(app.EditField_FS.Value);
            app.center_freq = str2double(app.EditField_Center_Freq.Value);
            app.T = str2double(app.EditField_Chirp_T.Value);
            app.BW = str2double(app.EditField_Chirp_BW.Value);
            app.f0 = str2double(app.EditField_Chirp_F0.Value);
            app.tags = str2double(string(app.ListBox_Tag.Items))';
            app.motion_freq = str2double(app.EditField_Motion_Frequency.Value);
            app.motion_amp = str2double(app.EditField_Motion_Amplitude.Value);
            app.snr = str2double(app.EditField_SNR.Value);
        end

        function syncToUI(app)
            format = '%g';
            app.EditField_FS.Value = num2str(app.fs, format);
            app.EditField_Center_Freq.Value = num2str(app.center_freq, format);
            app.EditField_Chirp_T.Value = num2str(app.T, format);
            app.EditField_Chirp_BW.Value = num2str(app.BW, format);
            app.EditField_Chirp_F0.Value = num2str(app.f0, format);
            if isempty(app.tags)
                app.ListBox_Tag.Items = {};
            else
                app.ListBox_Tag.Items = cellstr(num2str(app.tags, format));
            end
            app.EditField_Motion_Frequency.Value = num2str(app.motion_freq, format);
            app.EditField_Motion_Amplitude.Value = num2str(app.motion_amp, format);
            app.EditField_SNR.Value = num2str(app.snr, format);
        end

        
        function setResult(app, tag_freq)
            if ~isKey(app.analyze_result, tag_freq)
                uialert(app.LoMuUIFigure, '标签结果不存在！', '警告', 'Icon', 'warning');
            end

            curr_motion_amp = app.analyze_result{tag_freq}{1};
            curr_motion_freq = app.analyze_result{tag_freq}{2};
            time = app.analyze_result{tag_freq}{3};
            phase = app.analyze_result{tag_freq}{4};
            f_range = app.analyze_result{tag_freq}{5};
            p = app.analyze_result{tag_freq}{6};
            
            % 设置UI
            app.motion_amp = curr_motion_amp;
            app.motion_freq = curr_motion_freq;

            % 绘图
            plot(app.UIAxes_Distance, time, phase, "LineWidth", 1.5)
            set(app.UIAxes_Distance, "LineWidth", 1.5);
            set(app.UIAxes_Distance, "XGrid", "on");
            set(app.UIAxes_Distance, "YGrid", "on");
            xlabel(app.UIAxes_Distance, "时间（s）")
            ylabel(app.UIAxes_Distance, "距离（mm）")

            % 绘图
            plot(app.UIAxes_Estimation, f_range, p)
            set(app.UIAxes_Estimation, "XGrid", "on");
            set(app.UIAxes_Estimation, "YGrid", "on");
            xlabel(app.UIAxes_Estimation, "频率（Hz）")
            ylabel(app.UIAxes_Estimation, "概率")
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('lib')
            clc

            % 同步一次UI
            app.syncFromUI();

            % 初始化一些参数
            app.threshold = 2e3;            % 节点频移频率的误差范围

            % 搜索相关配置
            app.motion_freq_begin = 0;      % 联合估计搜索的运动频率起始
            app.motion_freq_end = 5;        % 联合估计搜索的运动频率结束
            app.motion_freq_n = 1e4;        % 联合估计搜索的精度

            % 其他配置
            app.dc_offset = true;           % DC直流偏置消除
            app.fft_n = 1e5;                % 傅里叶变换长度
            app.every_window_flag = true;   % 是否逐窗口寻找节点频率（持续跟踪消除傅里叶变换跳变算法）
            app.can_run = false;            % 是否可以运行
            
            % 缓存分析结果
            app.analyze_result = dictionary(0, {{0,0,0,0,0,0}});

            % 同步一次UI
            app.syncToUI();
        end

        % Button pushed function: Button_Import
        function Button_ImportPushed(app, event)
            % 打开文件选择器
            [file, path] = uigetfile({'*.json', 'JSON 文件 (*.json)'}, '选择一个配置文件');

            % 检查用户是否选择了文件
            if ~isequal(file, 0)
                % 打开文件
                fullPath = fullfile(path, file);
                config_file = fopen(fullPath);
                if config_file == -1
                    uialert(app.LoMuUIFigure, '配置文件不存在！', '错误', 'Icon', 'error');
                    return;
                end
                config = jsondecode(fscanf(config_file, '%s'));
                fclose(config_file);

                % 信号相关配置
                app.fs = app.getKey(config, 'fs', app.fs, true);                % 采样率
                app.center_freq = ...
                    app.getKey(config, 'center_freq', app.center_freq, true);   % 载波中心频率
                app.T = app.getKey(config, 'T', app.T, true);                   % Chirp符号持续时间
                app.BW = app.getKey(config, 'BW', app.BW, true);                % Chirp符号带宽
                app.f0 = ...
                    app.getKey(config, 'f0', -app.BW / 2, false);               % Chirp起始频率
                app.tags = app.getKey(config, 'tag', app.tags, true);             % 节点频移参考频率
                app.threshold = ...
                    app.getKey(config, 'threshold', app.threshold, false);      % 节点频移频率的误差范围
                % 搜索相关配置
                app.motion_freq_begin = ...
                    app.getKey(config, 'motion_freq_begin', app.motion_freq_begin, false);      % 联合估计搜索的运动频率起始
                app.motion_freq_end = ...
                    app.getKey(config, 'motion_freq_end', app.motion_freq_end, false);          % 联合估计搜索的运动频率结束
                app.motion_freq_n = ...
                    app.getKey(config, 'motion_freq_n', app.motion_freq_n, false);              % 联合估计搜索的精度
                % 其他配置
                app.dc_offset = ...
                    app.getKey(config, 'dc_offset', true, false);               % DC直流偏置消除
                app.fft_n = app.getKey(config, 'fft_n', app.fft_n, false);      % 傅里叶变换长度
                app.every_window_flag = ...
                    app.getKey(config, 'every_window_flag', app.every_window_flag, false);  % 是否逐窗口寻找节点频率（持续跟踪消除傅里叶变换跳变算法）

                % 同步UI
                app.syncToUI();
            end
        end

        % Button pushed function: Button_Add_Tag
        function Button_Add_TagPushed(app, event)
            % 设置提示信息
            prompt = {'请输入要添加的节点频率：'};

            % 设置对话框标题
            dlgtitle = '添加节点';

            % 设置每个输入框的行数和宽度
            dims = [1 35]; % [行数 字符宽度]

            % 设置默认值（可选）
            defaultInput = {'100000'};

            % 打开输入对话框并获取用户输入
            userInput = inputdlg(prompt, dlgtitle, dims, defaultInput);

            % 检查用户是否点击了“确定”按钮
            if ~isempty(userInput)
                new_tag = userInput{1};
                new_tag = str2double(new_tag);
                if isnan(new_tag)
                    uialert(app.LoMuUIFigure, '错误的节点频率！', '错误', 'Icon', 'error');
                else
                    app.tags(end + 1, :) = new_tag;
                end
            end

            % 同步UI
            app.syncToUI();

        end

        % Button pushed function: Button_Delete_Tag
        function Button_Delete_TagPushed(app, event)
            items = app.ListBox_Tag.Items;
            if isempty(items)
                return
            end

            selectedItem = app.ListBox_Tag.Value;
            index = find(strcmp(items, selectedItem));
            if ~isempty(index)
                items(index) = [];
                app.ListBox_Tag.Items = items;
                if ~isempty(items)
                    app.ListBox_Tag.Value = items{1};
                else
                    app.ListBox_Tag.Value = {};
                end
            end

            % 同步UI
            app.syncFromUI();
        end

        % Value changed function: EditField_FS
        function EditField_FSValueChanged(app, event)
            if ~isnan(str2double(app.EditField_FS.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Center_Freq
        function EditField_Center_FreqValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Center_Freq.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Chirp_T
        function EditField_Chirp_TValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Chirp_T.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Chirp_BW
        function EditField_Chirp_BWValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Chirp_BW.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Chirp_F0
        function EditField_Chirp_F0ValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Chirp_F0.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Motion_Frequency
        function EditField_Motion_FrequencyValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Motion_Frequency.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_Motion_Amplitude
        function EditField_Motion_AmplitudeValueChanged(app, event)
            if ~isnan(str2double(app.EditField_Motion_Amplitude.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Value changed function: EditField_SNR
        function EditField_SNRValueChanged(app, event)
            if ~isnan(str2double(app.EditField_SNR.Value))
                app.syncFromUI();
            end
            app.syncToUI();
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            if isempty(app.ListBox_Tag.Items)
                uialert(app.LoMuUIFigure, '没有选择节点！', '错误', 'Icon', 'error');
                return;
            end

            [filename, filepath] = uiputfile('sense_signal.cf32', '保存文件为');

            % 检查用户是否选择了文件
            if ~isequal(filename, 0) && ~isequal(filepath, 0)
                fullpath = fullfile(filepath, filename);
                t = 0 : 1 / app.fs : app.T - 1 / app.fs;
                up_chirp = chirp(t, app.f0, app.T, app.f0 + app.BW, "complex");
                t = 0 : 1 / app.fs : 10 -  1 / app.fs;
                y = app.motion_amp / 1000 * cos(2 * pi * app.motion_freq * t);
                tag_freq = str2double(app.ListBox_Tag.Value);
                app.data = generate_data(t, up_chirp, tag_freq, y, 10, app.center_freq, app.snr);
                write_complex_binary(app.data, fullpath);
                app.can_run = true;
                app.analyze_result = dictionary(0, {{0,0,0,0,0,0}});
            end

        end

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
            % 打开文件选择器
            [file, path] = uigetfile({'*.cf32', 'CF32 文件 (*.cf32)'}, '选择一个信号数据文件');

            % 检查用户是否选择了文件
            if ~isequal(file, 0)
                % 打开文件
                fullPath = fullfile(path, file);
                app.data = read_complex_binary(fullPath).';
                if app.data == 0
                    uialert(app.LoMuUIFigure, '信号数据文件不存在！', '错误', 'Icon', 'error');
                    return;
                end
                if isempty(app.ListBox_Tag.Items)
                    uialert(app.LoMuUIFigure, '未选中节点！', '错误', 'Icon', 'error');
                else
                    app.can_run = true;
                    app.analyze_result = dictionary(0, {{0,0,0,0,0,0}});
                end
            end
        end

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
            if ~app.can_run
                uialert(app.LoMuUIFigure, '请先检查配置或导入数据！', '错误', 'Icon', 'error');
                return;
            end

            % 使用缓存
            tag_freq = str2double(app.ListBox_Tag.Value);
            if isKey(app.analyze_result, tag_freq)
                app.setResult(tag_freq);
                app.syncToUI();
                uialert(app.LoMuUIFigure, '使用缓存结果！', '提示', 'Icon', 'info');
                return
            end

            progressDlg = uiprogressdlg(app.LoMuUIFigure, 'Title', '正在分析中', 'Cancelable', 'off');

            %% Chirp起点
            t = 0 : 1 / app.fs : app.T - 1 / app.fs;
            up_chirp = chirp(t, app.f0, app.T, app.f0 + app.BW, "complex");
            pre = app.data(1 : min(length(app.data), floor(100 * app.T * app.fs)));
            [corr_res, ~] = xcorr(pre, up_chirp);
            corr_res = abs(corr_res(length(pre) + 1 : length(corr_res)));
            [~, chirp_starts] = findpeaks(corr_res, "MinPeakDistance", (app.T / 2) * app.fs);

            %% 窗口处理
            window_size = length(up_chirp);
            window_begin = chirp_starts(floor(length(chirp_starts) / 2)) + 1;
            window_end = length(app.data);
            n_chirp_window = floor((window_end - window_begin + 1) / length(up_chirp));
            window_end = window_begin + n_chirp_window * length(up_chirp) - 1;
            n_window = n_chirp_window * length(up_chirp) / window_size;

            window_t = window_size * 1 / app.fs;
            processed_data = app.data(window_begin : window_end);

            % 去除直流偏置
            if app.dc_offset
                processed_data = processed_data - mean(processed_data);
            end

            % 共轭解调
            processed_data = conjugate_demodulate(processed_data, app.BW, app.T, app.fs);

            %% 求解信道
            tag_num = length(app.tags);
            up_freq = zeros(tag_num, 1);
            down_freq = zeros(tag_num, 1);
            up_idx = zeros(tag_num, 1);
            down_idx = zeros(tag_num, 1);

            freqs = zeros(1, n_window);
            up_freqs = zeros(tag_num, n_window);
            down_freqs = zeros(tag_num, n_window);
            hs = zeros(1, n_window);
            up_hs = zeros(tag_num, n_window);
            down_hs = zeros(tag_num, n_window);

            % 初步确认激励信号与反向散射信号位置
            [fft_f, fft_res] = compute_fft(processed_data, app.fs, app.fft_n);

            [pk, ~] = max(abs(fft_res));
            [~, locs] = findpeaks(abs(fft_res), "MinPeakHeight", 0.5 * pk);
            baseband_freqs = fft_f(locs);
            [~, idx] = min(abs(baseband_freqs - 0));
            baseband_freq = baseband_freqs(idx);
            for j = 1 : tag_num
                [up_freq(j), down_freq(j), up_h, down_h, up_idx(j), down_idx(j)] = get_up_down(fft_res, app.fs, app.tags(j), app.threshold, baseband_freq);
            end

            % app.snr = 20 * log10((abs(sqrt(up_h .* down_h))) / mean(abs(fft_res)));
            signal_strength = abs(sqrt(up_h .* down_h)) .^ 2;
            noise_signal = sum(abs(processed_data) .^ 2);
            app.snr = 10 * log10(signal_strength / noise_signal);

            for i = 1 : n_window
                window_data = processed_data((i - 1) * window_size + 1 : i * window_size);
                % 加窗函数
                window_data = window_function(window_data);
                [fft_f, fft_res] = compute_fft(window_data, app.fs, app.fft_n);
                if app.every_window_flag
                    [pk, ~] = max(abs(fft_res));
                    [~, locs] = findpeaks(abs(fft_res), "MinPeakHeight", 0.5 * pk);
                    baseband_freqs = fft_f(locs);
                    [~, idx] = min(abs(baseband_freqs - 0));
                    idx = locs(idx);
                    baseband_freq = fft_f(idx);
                    for j = 1 : tag_num
                        [~, ~, ~, ~, up_idx(j), down_idx(j)] = get_up_down(fft_res, app.fs, app.tags(j), app.threshold, baseband_freq);
                    end
                end
                % 提取信号
                freqs(i) = fft_f(idx);
                hs(i) = fft_res(idx);
                up_freqs(:, i) = fft_f(up_idx);
                down_freqs(:, i) = fft_f(down_idx);
                up_hs(:, i) = fft_res(up_idx);
                down_hs(:, i) = fft_res(down_idx);
                % 提示
                progressDlg.Value = i / n_window;
                progressDlg.Message = ['进度：', num2str(i / n_window * 100), '%'];
            end

            % 修正
            if app.every_window_flag
                % 持续跟踪消除傅里叶变换跳变算法
                for i = 2 : n_window
                    hs(i) = hs(i) .* exp(1j * pi * (freqs(i) - freqs(1)) * window_t);
                    up_hs(:, i) = up_hs(:, i) .* exp(1j * pi * (up_freqs(:, i) - up_freqs(:, 1)) * window_t);
                    down_hs(:, i) = down_hs(:, i) .* exp(1j * pi * (down_freqs(:, i) - down_freqs(:, 1)) * window_t);
                end
            end

            %% 信号处理
            for j = 1 : tag_num
                % 收发端偏移消除
                [up_hs_div, down_hs_div] = syn_transceivers(hs, up_hs(j, :), down_hs(j, :));

                % 节点频率漂移消除 + 持续跟踪消歧义算法
                up_down_sqrt_hs = up_down_sqrt(up_hs_div, down_hs_div);

                % 时间戳
                time = 0 : window_t : (length(up_down_sqrt_hs) - 1) * window_t;

                % 相位
                phase = unwrap(angle(up_down_sqrt_hs));
                phase = remove_outlier(phase);
                lambda = 3e8 / app.center_freq * 1000; % 单位（mm）
                phase = phase ./ (2 * pi) * lambda / 2;
                % phase = normalize(phase, "range", [-1, 1]);

                % 统计
                [~, curr_motion_amp, ~, ~] = get_motion_information(phase, window_t, app.motion_freq_begin, app.motion_freq_end);

                % 幅度
                amplitude = abs(up_down_sqrt_hs);
                amplitude = remove_outlier(amplitude);
                amplitude = normalize(amplitude, "range", [-1, 1]);

                % 联合估计
                [f_range, loss] = joint_estimate(phase, amplitude, time, ...
                    app.motion_freq_begin, app.motion_freq_end, app.motion_freq_n, 1, 1);

                % 统计（忽略掉低频噪声）
                index = f_range > 0.3;
                f_range = f_range(index);
                loss = loss(index);
                [~, idx] = min(loss);
                curr_motion_freq = f_range(idx);
                p = -loss + abs(min(-loss));
                p = p / max(p);

                % 缓存结果
                app.analyze_result(app.tags(j)) = {{curr_motion_amp, curr_motion_freq, time, phase, f_range, p}};
            end
            
            app.setResult(tag_freq);
            app.syncToUI();
            close(progressDlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoMuUIFigure = uifigure('Visible', 'off');
            app.LoMuUIFigure.Name = 'LoMu';
            app.GridLayout = uigridlayout(app.LoMuUIFigure);
            app.GridLayout_Right = uigridlayout(app.GridLayout);
            app.UIAxes_Estimation = uiaxes(app.GridLayout_Right);
            title(app.UIAxes_Estimation, 'Title')
            xlabel(app.UIAxes_Estimation, 'X')
            ylabel(app.UIAxes_Estimation, 'Y')
            zlabel(app.UIAxes_Estimation, 'Z')
            app.UIAxes_Distance = uiaxes(app.GridLayout_Right);
            title(app.UIAxes_Distance, 'Title')
            xlabel(app.UIAxes_Distance, 'X')
            ylabel(app.UIAxes_Distance, 'Y')
            zlabel(app.UIAxes_Distance, 'Z')
            app.Label_Distance = uilabel(app.GridLayout_Right);
            app.Label_Distance.Text = '目标位置变化';
            app.Label_Estimation = uilabel(app.GridLayout_Right);
            app.Label_Estimation.Text = '联合估计算法';
            app.GridLayout_Left = uigridlayout(app.GridLayout);
            app.GridLayout_Parameter = uigridlayout(app.GridLayout_Left);
            app.Label = uilabel(app.GridLayout_Parameter);
            app.Label.Text = '采样率（Hz）';
            app.EditField_FS = uieditfield(app.GridLayout_Parameter, 'text');
            app.EditField_FS.ValueChangedFcn = createCallbackFcn(app, @EditField_FSValueChanged, true);
            app.EditField_FS.Value = '1e6';
            app.Label_2 = uilabel(app.GridLayout_Parameter);
            app.Label_2.Text = '载波中心频率（Hz）';
            app.EditField_Center_Freq = uieditfield(app.GridLayout_Parameter, 'text');
            app.EditField_Center_Freq.ValueChangedFcn = createCallbackFcn(app, @EditField_Center_FreqValueChanged, true);
            app.EditField_Center_Freq.Value = '433e6';
            app.Label_3 = uilabel(app.GridLayout_Parameter);
            app.Label_3.Text = '标签频率（Hz）';
            app.ListBox_Tag = uilistbox(app.GridLayout_Parameter);
            app.ListBox_Tag.Items = {'100e3', '200e3', '300e3'};
            app.ListBox_Tag.Value = '100e3';
            app.ChirpsLabel = uilabel(app.GridLayout_Parameter);
            app.ChirpsLabel.Text = 'Chirp持续时间（s）';
            app.EditField_Chirp_T = uieditfield(app.GridLayout_Parameter, 'text');
            app.EditField_Chirp_T.ValueChangedFcn = createCallbackFcn(app, @EditField_Chirp_TValueChanged, true);
            app.EditField_Chirp_T.Value = '8.192e-3';
            app.ChirpHzLabel = uilabel(app.GridLayout_Parameter);
            app.ChirpHzLabel.Text = 'Chirp带宽（Hz）';
            app.EditField_Chirp_BW = uieditfield(app.GridLayout_Parameter, 'text');
            app.EditField_Chirp_BW.ValueChangedFcn = createCallbackFcn(app, @EditField_Chirp_BWValueChanged, true);
            app.EditField_Chirp_BW.Value = '500e3';
            app.ChirpHzLabel_2 = uilabel(app.GridLayout_Parameter);
            app.ChirpHzLabel_2.Text = 'Chirp起始频率（Hz）';
            app.EditField_Chirp_F0 = uieditfield(app.GridLayout_Parameter, 'text');
            app.EditField_Chirp_F0.ValueChangedFcn = createCallbackFcn(app, @EditField_Chirp_F0ValueChanged, true);
            app.EditField_Chirp_F0.Value = '-250e3';
            app.Button_Add_Tag = uibutton(app.GridLayout_Parameter, 'push');
            app.Button_Add_Tag.ButtonPushedFcn = createCallbackFcn(app, @Button_Add_TagPushed, true);
            app.Button_Add_Tag.Text = '添加标签';
            app.Button_Delete_Tag = uibutton(app.GridLayout_Parameter, 'push');
            app.Button_Delete_Tag.ButtonPushedFcn = createCallbackFcn(app, @Button_Delete_TagPushed, true);
            app.Button_Delete_Tag.Text = '删除标签';
            app.Button_Import = uibutton(app.GridLayout_Left, 'push');
            app.Button_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportPushed, true);
            app.Button_Import.Text = '导入配置';
            app.Label_Information = uilabel(app.GridLayout_Left);
            app.Label_Information.Text = '目标运动信息';
            app.GridLayout_Information = uigridlayout(app.GridLayout_Left);
            app.Label_5 = uilabel(app.GridLayout_Information);
            app.Label_5.Text = '运动频率（Hz）';
            app.EditField_Motion_Frequency = uieditfield(app.GridLayout_Information, 'text');
            app.EditField_Motion_Frequency.ValueChangedFcn = createCallbackFcn(app, @EditField_Motion_FrequencyValueChanged, true);
            app.EditField_Motion_Frequency.Value = '1';
            app.Label_6 = uilabel(app.GridLayout_Information);
            app.Label_6.Text = '运动幅度（mm）';
            app.EditField_Motion_Amplitude = uieditfield(app.GridLayout_Information, 'text');
            app.EditField_Motion_Amplitude.ValueChangedFcn = createCallbackFcn(app, @EditField_Motion_AmplitudeValueChanged, true);
            app.EditField_Motion_Amplitude.Value = '10';
            app.GridLayout_Generate = uigridlayout(app.GridLayout_Left);
            app.SNRdBLabel = uilabel(app.GridLayout_Generate);
            app.SNRdBLabel.Text = 'SNR（dB）';
            app.EditField_SNR = uieditfield(app.GridLayout_Generate, 'text');
            app.EditField_SNR.ValueChangedFcn = createCallbackFcn(app, @EditField_SNRValueChanged, true);
            app.EditField_SNR.Value = '20';
            app.GridLayout_Data = uigridlayout(app.GridLayout_Left);
            app.Button_Load = uibutton(app.GridLayout_Data, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Text = '载入数据';
            app.Button_Run = uibutton(app.GridLayout_Data, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Text = '运行';
            app.Button_Generate = uibutton(app.GridLayout_Left, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Visible = 'off';
            app.Button_Generate.Text = '生成数据';
            % Show the figure after all components are created
            app.LoMuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoMuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoMuUIFigure)
        end
    end
end